classdef Crop_dataset_by_hand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResizeDropDown         matlab.ui.control.DropDown
        ResizeDropDownLabel    matlab.ui.control.Label
        varnameEditField       matlab.ui.control.EditField
        varnameEditFieldLabel  matlab.ui.control.Label
        ButtonGroup            matlab.ui.container.ButtonGroup
        Button1024             matlab.ui.control.RadioButton
        Button512              matlab.ui.control.RadioButton
        Button256              matlab.ui.control.RadioButton
        SourcePathEditField    matlab.ui.control.EditField
        SavePathEditField      matlab.ui.control.EditField
        SavePathButton         matlab.ui.control.Button
        SaveButton             matlab.ui.control.Button
        SourcePathButton       matlab.ui.control.Button
        ListBox                matlab.ui.control.ListBox
        Label                  matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        savePath % Description
        sourcePath
        files
        size = 256;
        img_Origin
        img
        roi
    end
    
    methods (Access = private)
        
        function val = directLoad(app, filepath)
            % 因为导入的load是个结构体，所以在只有一个变量的情况下，直接导入变量本身
            varStruct = load(filepath);
            namecell = fieldnames(varStruct);
            val = getfield(varStruct, char(namecell));
        end

        function allevents(app, src, evt)
            % 坐标移动的监听程序,不过不需要了
            evname = evt.EventName;
            switch(evname)
                case{'MovingROI'}
                    disp(['ROI moving previous position: ' mat2str(evt.PreviousPosition)]);
                    disp(['ROI moving current position: ' mat2str(evt.CurrentPosition)]);
                case{'ROIMoved'}
                    disp(['ROI moved previous position: ' mat2str(evt.PreviousPosition)]);
                    disp(['ROI moved current position: ' mat2str(evt.CurrentPosition)]);
            end
        end
        function img_update(app)
            imshow(app.img(:,:,[28,19,1]), [], "Parent", app ...
                .UIAxes); %直接显示彩色图像
            axis(app.UIAxes, "tight");
            % 同时绘制size大小的剪裁框
            app.roi = drawrectangle("InteractionsAllowed","translate", ...
                "Position", [0,0,app.size,app.size],...
                "Parent",app.UIAxes);
            
            % 因为把roi加入了类属性，不用再监听了
%             addlistener(app.roi,'MovingROI',@app.allevents);
%             addlistener(app.roi,'ROIMoved',@app.allevents);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIAxes.Interactions = [];
        end

        % Button pushed function: SourcePathButton
        function SourcePathButtonPushed(app, event)
            % 更新源图像地址
            filePath = uigetdir("D:\Leo_files\MST\datasets", "请选择要裁切的数据集");
            drawnow;
            figure(app.UIFigure);
            if filePath ~= 0
                app.sourcePath = filePath;
                app.SourcePathEditField.Value = app.sourcePath;
                app.files = dir(fullfile(app.sourcePath, '*.mat'));
                app.ListBox.Items = {app.files.name};
            end
        end

        % Button pushed function: SavePathButton
        function SavePathButtonPushed(app, event)
            % 更新保存地址
            filePath = uigetdir("D:\Leo_files\MST\datasets", "请选择保存路径");
            drawnow;
            figure(app.UIFigure);
            if filePath ~= 0
                app.savePath = filePath;
                app.SavePathEditField.Value = app.savePath;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            % 直接更新ROI的大小
            selectedButton = app.ButtonGroup.SelectedObject;
            app.size = str2double(selectedButton.Tag);
            app.roi.Position([3,4]) = app.size; % 直接调整长宽
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            % 选中文件后，读入图像，绘制在左侧的坐标轴中
            d = uiprogressdlg(app.UIFigure,"Indeterminate","on","Title","Reading");
            value = app.ListBox.Value;
            imgPath = fullfile(app.sourcePath, value);
            app.img_Origin = app.directLoad(imgPath);
            app.img = app.img_Origin; % 第一次读入的时候，二者是相等的，如果缩放变了，那么后面就不相等了。
            app.ResizeDropDown.Value = '1';
            close(d);
            app.img_update();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % 剪裁相应的图像并保存，显示提示信息
            pos = round(app.roi.Position); % pos[y, x, width, height], 因为imshow的坐标是颠倒的
            filename = fullfile(app.savePath,strcat(app.ListBox.Value(1:end-4), "_", num2str(pos(2)), "_", num2str(pos(1))));
            answer = questdlg("确认保存吗？", "保存确认", "是","否","是");
            if answer == "是"
                try
                    eval([app.varnameEditField.Value, '= single(app.img(pos(2)+1:pos(2)+pos(3), pos(1)+1:pos(1)+pos(4), :));']);
                    eval(['HSI=', app.varnameEditField.Value]);
                    RGB = uint8(HSI(:,:,[28,19,1])*255);
                    save(strcat(filename,".mat"), app.varnameEditField.Value);
                    imwrite(RGB, strcat(filename, ".jpg")); % 记得同时保存JPG格式图像，方面做PPT以及观察
                    msgbox("保存成功");
                catch
                    msgbox("保存失败");
                end
            end     
        end

        % Value changed function: SourcePathEditField
        function SourcePathEditFieldValueChanged(app, event)
            % 手动输入的方式也可以刷新
            app.sourcePath = app.SourcePathEditField.Value;
            app.files = dir(strcat(app.sourcePath, '\*.mat'));
            app.ListBox.Items = {app.files.name};
        end

        % Value changed function: ResizeDropDown
        function ResizeDropDownValueChanged(app, event)
            value = app.ResizeDropDown.Value;
            app.img = imresize(app.img_Origin, str2double(value)); %改变缩放
            app.img_update();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Crop_datasets_by_hand';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '光谱图像')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = {''; ''};
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = {};
            app.SourcePathButton = uibutton(app.UIFigure, 'push');
            app.SourcePathButton.ButtonPushedFcn = createCallbackFcn(app, @SourcePathButtonPushed, true);
            app.SourcePathButton.Text = 'Source Path';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = {'Save'; ''};
            app.SavePathButton = uibutton(app.UIFigure, 'push');
            app.SavePathButton.ButtonPushedFcn = createCallbackFcn(app, @SavePathButtonPushed, true);
            app.SavePathButton.Text = 'Save Path';
            app.SavePathEditField = uieditfield(app.UIFigure, 'text');
            app.SourcePathEditField = uieditfield(app.UIFigure, 'text');
            app.SourcePathEditField.ValueChangedFcn = createCallbackFcn(app, @SourcePathEditFieldValueChanged, true);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button256 = uiradiobutton(app.ButtonGroup);
            app.Button256.Tag = '256';
            app.Button256.Text = '256';
            app.Button256.Value = true;
            app.Button512 = uiradiobutton(app.ButtonGroup);
            app.Button512.Tag = '512';
            app.Button512.Text = '512';
            app.Button1024 = uiradiobutton(app.ButtonGroup);
            app.Button1024.Tag = '1024';
            app.Button1024.Text = '1024';
            app.varnameEditFieldLabel = uilabel(app.UIFigure);
            app.varnameEditFieldLabel.Text = 'var name';
            app.varnameEditField = uieditfield(app.UIFigure, 'text');
            app.varnameEditField.Value = 'HSI';
            app.ResizeDropDownLabel = uilabel(app.UIFigure);
            app.ResizeDropDownLabel.Text = 'Resize';
            app.ResizeDropDown = uidropdown(app.UIFigure);
            app.ResizeDropDown.Items = {'1', '0.5', '0.25'};
            app.ResizeDropDown.ValueChangedFcn = createCallbackFcn(app, @ResizeDropDownValueChanged, true);
            app.ResizeDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Crop_dataset_by_hand

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end